classdef BrightenUpMartAnalyticsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MainGrid            matlab.ui.container.GridLayout
        BodyGrid            matlab.ui.container.GridLayout
        OptPanel            matlab.ui.container.Panel
        OptGrid             matlab.ui.container.GridLayout
        StatusGrid          matlab.ui.container.GridLayout
        OptStatusLabel      matlab.ui.control.Label
        StatusLamp          matlab.ui.control.Lamp
        ResultGrid          matlab.ui.container.GridLayout
        SavingsLabel        matlab.ui.control.Label
        SavingsCaption      matlab.ui.control.Label
        NaiveCostLabel      matlab.ui.control.Label
        NaiveCostCaption    matlab.ui.control.Label
        OptCostLabel        matlab.ui.control.Label
        OptCostCaption      matlab.ui.control.Label
        CostTable           matlab.ui.control.Table
        CostLabel           matlab.ui.control.Label
        SharesGrid          matlab.ui.container.GridLayout
        DemandShareTable    matlab.ui.control.Table
        SupplyShareTable    matlab.ui.control.Table
        SharesLabel         matlab.ui.control.Label
        OptCtrlGrid         matlab.ui.container.GridLayout
        ReloadCostsButton   matlab.ui.control.Button
        ShipInfoLabel       matlab.ui.control.Label
        ShipMonthDropDown   matlab.ui.control.DropDown
        ShipMonthCaption    matlab.ui.control.Label
        ForecastPanel       matlab.ui.container.Panel
        FcstGrid            matlab.ui.container.GridLayout
        FcstStatusLabel     matlab.ui.control.Label
        FcstCtrlGrid        matlab.ui.container.GridLayout
        ResetButton         matlab.ui.control.Button
        FocusMonthDropDown  matlab.ui.control.DropDown
        FocusCaption        matlab.ui.control.Label
        PlotStyleDropDown   matlab.ui.control.DropDown
        PlotStyleCaption    matlab.ui.control.Label
        HorizonSpinner      matlab.ui.control.Spinner
        HorizonCaption      matlab.ui.control.Label
        ForecastAxes        matlab.ui.control.UIAxes
        HeaderLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        BUS = ""
        DataDir = ""
        DcNames = ""
        DcShare = ""
        DcShare0 = ""
        Demand = ""
        FocusIdx = ""
        ForecastSales = ""
        FutureDates = ""
        Model = ""
        NaiveCost = ""
        OptCost = ""
        Plan = ""
        PlanHeatmap = ""
        PlannedSpend = ""
        ShipCost = ""
        ShipCost0 = ""
        StoreNames = ""
        StoreShare = ""
        StoreShare0 = ""
        Supply = ""
    end

    methods (Access = private)
        function c = buildShareData(~, names, shares)
        %BUILDSHAREDATA A name column beside its share, shown as a percentage.
        %   Cell data rather than a table, so ColumnFormat 'bank' can hold every
        %   share to two decimals instead of mixing 47 with 26.5000.
            c = [cellstr(names(:)), num2cell(round(100 * shares(:), 2))];
        end
        function t = buildTableData(app, values)
        %BUILDTABLEDATA Put a 3x5 matrix behind a leading distribution-centre column.
            t = array2table(values, 'VariableNames', ...
                matlab.lang.makeValidName(cellstr(app.StoreNames(:)')));
            t = addvars(t, app.DcNames(:), 'Before', 1, ...
                'NewVariableNames', 'DistributionCentre');
        end
        function drawForecast(app)
        %DRAWFORECAST Plot history in blue and the forecast in red.
        %   Two views: one continuous timeline, or every calendar year overlaid on a
        %   common 1-12 month axis.
            ax = app.ForecastAxes;
            cla(ax, 'reset');
            delete(findall(ax, 'Type', 'constantline'));   % an xline survives cla
            hold(ax, 'on');
        
            actualColour   = [0.00 0.35 0.75];
            forecastColour = [0.85 0.16 0.16];
            focus = app.FocusIdx;
        
            if strcmp(app.PlotStyleDropDown.Value, 'yoy')
                % --- One line per calendar year, on a shared 1-12 month axis ------
                histYears = unique(year(app.BUS.Month));
                n = numel(histYears);
                w = linspace(0, 1, max(n, 2))';
                w = w(1:n);
                blues = (1 - w) .* [0.72 0.83 0.94] + w .* [0.00 0.25 0.55];
                for i = 1:n
                    sel = year(app.BUS.Month) == histYears(i);
                    plot(ax, month(app.BUS.Month(sel)), app.BUS.Sales(sel), '-o', ...
                        'Color', blues(i,:), 'LineWidth', 1.2, 'MarkerSize', 4, ...
                        'MarkerFaceColor', blues(i,:), ...
                        'DisplayName', sprintf('%d actual', histYears(i)));
                end
        
                fcstYears = unique(year(app.FutureDates));
                for i = 1:numel(fcstYears)
                    sel = year(app.FutureDates) == fcstYears(i);
                    plot(ax, month(app.FutureDates(sel)), app.ForecastSales(sel), '--+', ...
                        'Color', forecastColour, 'LineWidth', 1.6, 'MarkerSize', 8, ...
                        'DisplayName', sprintf('%d forecast', fcstYears(i)));
                end
        
                if focus >= 1
                    xline(ax, month(app.FutureDates(focus)), '--', ...
                        char(string(app.FutureDates(focus), "MMM") + " promotion"), ...
                        'Color', [0.35 0.35 0.35], 'LineWidth', 1.4, ...
                        'LabelVerticalAlignment', 'bottom', 'HandleVisibility', 'off');
                end
        
                ax.XLim = [0.5 12.5];
                ax.XTick = 1:12;
                ax.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun', ...
                                 'Jul','Aug','Sep','Oct','Nov','Dec'};
                ax.XLabel.String = 'Month of year';
            else
                % --- One continuous series ---------------------------------------
                plot(ax, app.BUS.Month, app.BUS.Sales, '-o', ...
                    'Color', actualColour, 'LineWidth', 1.3, 'MarkerSize', 3.5, ...
                    'MarkerFaceColor', actualColour, 'DisplayName', 'Actual sales');
        
                % Start the forecast at the last actual point so the lines join up.
                plot(ax, [app.BUS.Month(end); app.FutureDates], ...
                         [app.BUS.Sales(end); app.ForecastSales], '--+', ...
                    'Color', forecastColour, 'LineWidth', 1.6, 'MarkerSize', 8, ...
                    'DisplayName', 'Forecast sales');
        
                if focus >= 1
                    xline(ax, app.FutureDates(focus), '--', ...
                        char(string(app.FutureDates(focus), "MMM yyyy") + " promotion"), ...
                        'Color', [0.35 0.35 0.35], 'LineWidth', 1.4, ...
                        'LabelVerticalAlignment', 'bottom', 'HandleVisibility', 'off');
                end
        
                ax.XLabel.String = 'Month';
            end
        
            hold(ax, 'off');
            grid(ax, 'on');
            ax.YLabel.String = 'Unit sales';
            ax.Title.String  = 'Brighten Up Mart monthly unit sales';
            legend(ax, 'Location', 'northwest', 'FontSize', 9);
        end
        function s = fmtTHB(~, value)
        %FMTTHB Format a cost as THB with thousands separators.
            s = [regexprep(sprintf('%.0f', value), '\d{1,3}(?=(\d{3})+$)', '$0,') ' THB'];
        end
        function [alloc, naiveCost] = naiveAllocation(app, supply, demand, cost)
        %NAIVEALLOCATION Greedy first-come, first-served allocation - the habit that
        %   optimization is being compared against. Each store is filled from
        %   whichever distribution centre still has stock, ignoring cost entirely.
            supply = supply(:);
            demand = demand(:)';
            remaining = supply;
            alloc = zeros(numel(supply), numel(demand));
        
            for j = 1:numel(demand)
                d = demand(j);
                for i = 1:numel(supply)
                    take = min(remaining(i), d);
                    alloc(i,j) = take;
                    remaining(i) = remaining(i) - take;
                    d = d - take;
                    if d <= 0
                        break
                    end
                end
            end
        
            naiveCost = sum(sum(cost .* alloc));
        end
        function refreshAll(app)
        %REFRESHALL Re-run the forecast, redraw it, then re-solve the shipping plan.
        %   Every control funnels through here (or through solveTransport) so the
        %   shipping plan can never disagree with the forecast beside it.
            runForecast(app);
            drawForecast(app);
            syncShipMonths(app);
            solveTransport(app);
        end
        function runForecast(app)
        %RUNFORECAST Roll the regression model forward month by month.
        %   Mirrors createForecast in Solution_LowCode_MATLAB_Business_Analytics.mlx:
        %   each prediction is fed back as the next month's LastMonth value, and
        %   SameMonthLastYear is read from history (which caps the horizon at 12).
            h = app.HorizonSpinner.Value;
            dates = app.BUS.Month(end) + calmonths(1:h)';
        
            % Keep the focus-month dropdown in step with the horizon.
            focus = app.FocusMonthDropDown.Value;
            if isempty(focus) || focus > h
                focus = 0;                      % 0 == None
            end
            app.FocusMonthDropDown.Items     = [{'None'}, cellstr(string(dates, "MMM yyyy"))'];
            app.FocusMonthDropDown.ItemsData = 0:h;
            app.FocusMonthDropDown.Value     = focus;
        
            % Planned marketing spend: a Poisson draw around the recent median, with
            % the focus month overwritten by the promotional spike.
            recent = app.BUS.MarketingSpend(end-12:end);
            rng(7);                              % fixed seed so a demo reproduces
            spend = poissrnd(median(recent), [h 1]);
            boost = round(median(recent) + 2*std(recent));
            if focus >= 1
                spend(focus) = boost;
            end
        
            forecast = zeros(h, 1);
            lastMonthSales = app.BUS.Sales(end);
            for k = 1:h
                row = table(k, lastMonthSales, app.BUS.Sales(end-12+k), spend(k), ...
                    'VariableNames', {'MonthOfYear','LastMonth','SameMonthLastYear','MarketingSpend'});
                forecast(k) = app.Model.predictFcn(row);
                lastMonthSales = forecast(k);
            end
        
            app.FutureDates   = dates;
            app.PlannedSpend  = spend;
            app.ForecastSales = forecast;
            app.FocusIdx      = focus;
        
            if focus >= 1
                app.FcstStatusLabel.Text = sprintf( ...
                    'Horizon %d months.  Promotion in %s: marketing spend boosted to %d (normal months average %d).', ...
                    h, string(dates(focus), "MMMM yyyy"), boost, round(median(recent)));
            else
                app.FcstStatusLabel.Text = sprintf( ...
                    'Horizon %d months.  No promotion month selected - marketing spend stays near its recent median of %d.', ...
                    h, round(median(recent)));
            end
        end
        function setInfeasible(app, message)
        %SETINFEASIBLE Report a problem without throwing, so a mistyped cell during a
        %   live demo is always recoverable.
            app.PlanHeatmap.ColorData = zeros(numel(app.DcNames), numel(app.StoreNames));
            app.PlanHeatmap.ColorLimits = [0 1];
            app.OptCostLabel.Text   = '--';
            app.NaiveCostLabel.Text = '--';
            app.SavingsLabel.Text   = '--';
            app.StatusLamp.Color    = [0.85 0.20 0.20];
            app.OptStatusLabel.Text = message;
        end
        function solveTransport(app)
        %SOLVETRANSPORT Split the selected month's forecast into store demand and DC
        %   supply using the share tables, then solve for the least-cost shipping
        %   plan as a linear program.
            k = app.ShipMonthDropDown.Value;
            if isempty(k) || k < 1 || k > numel(app.ForecastSales)
                return
            end
        
            % Keep the editable share tables showing current app state.
            app.SupplyShareTable.Data = buildShareData(app, app.DcNames, app.DcShare);
            app.DemandShareTable.Data = buildShareData(app, app.StoreNames, app.StoreShare);
        
            % The forecast for the shipping month IS total demand.
            totalDemand = round(app.ForecastSales(k));
            totalSupply = ceil(totalDemand * 1.03);          % 3 percent buffer
        
            % Rescale the shares to 100% first. With the shares as they come from
            % Excel this changes nothing, but it means an edited share actually moves
            % stock instead of being handed straight back by the rounding correction
            % below.
            if sum(app.DcShare) <= 0 || sum(app.StoreShare) <= 0
                setInfeasible(app, 'Supply and demand shares must add up to more than zero.');
                return
            end
            dcShare    = app.DcShare / sum(app.DcShare);
            storeShare = app.StoreShare / sum(app.StoreShare);
        
            demand = round(totalDemand * storeShare);
            demand(end) = demand(end) + (totalDemand - sum(demand));   % keep totals exact
            supply = round(totalSupply * dcShare);
            supply(1) = supply(1) + (totalSupply - sum(supply));
        
            app.Demand = demand;
            app.Supply = supply;
        
            cost = app.ShipCost;
            if any(~isfinite(cost(:))) || any(cost(:) < 0)
                setInfeasible(app, 'Shipping costs must be finite and non-negative.');
                return
            end
            if any(demand < 0) || any(supply < 0)
                setInfeasible(app, ['The share tables push a store or a distribution centre ' ...
                    'below zero. Bring the shares back towards 100%.']);
                return
            end
        
            shipUnit = optimvar('shipUnit', numel(supply), numel(demand), 'LowerBound', 0);
            problem = optimproblem;
            problem.Objective = sum(sum(cost .* shipUnit));
            problem.Constraints.supply = sum(shipUnit, 2) <= supply;
            problem.Constraints.demand = sum(shipUnit, 1)' == demand;
        
            [solution, optCost, exitflag] = solve(problem, ...
                'Options', optimoptions('linprog', 'Display', 'off'));
        
            if exitflag <= 0 || isempty(solution.shipUnit)
                setInfeasible(app, 'No plan can meet every store demand from the available supply.');
                return
            end
        
            plan = round(solution.shipUnit);
            [~, naiveCost] = naiveAllocation(app, supply, demand, cost);
            savings = naiveCost - optCost;
        
            app.Plan      = plan;
            app.OptCost   = optCost;
            app.NaiveCost = naiveCost;
        
            % --- Cost table and the plan heatmap ---------------------------------
            app.CostTable.Data = buildTableData(app, app.ShipCost);
            app.PlanHeatmap.ColorData = plan;
            app.PlanHeatmap.ColorLimits = [0 max(1, max(plan(:)))];
        
            % --- Readouts --------------------------------------------------------
            app.ShipInfoLabel.Text = sprintf('Demand %d units   |   supply %d units (+3%%)', ...
                totalDemand, totalSupply);
            app.OptCostLabel.Text   = fmtTHB(app, optCost);
            app.NaiveCostLabel.Text = fmtTHB(app, naiveCost);
            if naiveCost > 0
                app.SavingsLabel.Text = sprintf('%s  (%.1f%%)', fmtTHB(app, savings), ...
                    100 * savings / naiveCost);
            else
                app.SavingsLabel.Text = fmtTHB(app, savings);
            end
        
            message = sprintf('Optimal plan for %s uses %d of %d possible routes.', ...
                string(app.FutureDates(k), "MMMM yyyy"), nnz(plan), numel(plan));
        
            % Shares that no longer total 100% still solve - they are rescaled - but
            % say so out loud rather than quietly changing what the user typed.
            drift = '';
            if abs(sum(app.DcShare) - 1) > 0.005
                drift = sprintf(' Supply shares total %.1f%%', 100*sum(app.DcShare));
            end
            if abs(sum(app.StoreShare) - 1) > 0.005
                if isempty(drift)
                    drift = sprintf(' Demand shares total %.1f%%', 100*sum(app.StoreShare));
                else
                    drift = sprintf('%s and demand shares total %.1f%%', drift, 100*sum(app.StoreShare));
                end
            end
            if isempty(drift)
                app.StatusLamp.Color = [0.20 0.70 0.30];
                app.OptStatusLabel.Text = message;
            else
                app.StatusLamp.Color = [0.90 0.65 0.10];
                app.OptStatusLabel.Text = [message drift ' - rescaled to 100% for the split.'];
            end
        end
        function syncShipMonths(app)
        %SYNCSHIPMONTHS Repopulate the shipping-month dropdown from the forecast,
        %   preserving the current selection whenever it is still in range.
            h = numel(app.FutureDates);
            keep = app.ShipMonthDropDown.Value;
            if isempty(keep) || keep < 1 || keep > h
                keep = 1;
            end
            app.ShipMonthDropDown.Items     = cellstr(string(app.FutureDates, "MMM yyyy"))';
            app.ShipMonthDropDown.ItemsData = 1:h;
            app.ShipMonthDropDown.Value     = keep;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Resolve the data folder from the app's own location so the app runs from anywhere.
            appDir = fileparts(which(class(app)));
            if isempty(appDir)
                appDir = pwd;
            end
            app.DataDir = fullfile(appDir, "data");
            % --- Sales history and the trained regression model -----------------------
            app.BUS = readtimetable(fullfile(app.DataDir, "BrightenUpMartSales.csv"), ...
                "RowTimes", "Month");
            loaded = load(fullfile(app.DataDir, "RbLin.mat"));
            fn = fieldnames(loaded);
            app.Model = loaded.(fn{1});
            % --- Distribution network scenario (read from Excel, never hardcoded) -----
            xlsFile = fullfile(app.DataDir, "TransportOpt.xlsx");
            app.ShipCost0   = readmatrix(xlsFile, "Sheet", "Shipping Cost", "Range", "C3:G5");
            app.ShipCost    = app.ShipCost0;
            app.DcNames     = readmatrix(xlsFile, "Sheet", "Supply Share", "Range", "B3:B5", "OutputType", "string");
            app.DcShare0    = readmatrix(xlsFile, "Sheet", "Supply Share", "Range", "C3:C5");
            app.DcShare     = app.DcShare0;
            app.StoreNames  = readmatrix(xlsFile, "Sheet", "Demand Share", "Range", "B3:B7", "OutputType", "string");
            app.StoreShare0 = readmatrix(xlsFile, "Sheet", "Demand Share", "Range", "C3:C7");
            app.StoreShare  = app.StoreShare0;
            % --- Table headers -------------------------------------------------------
            app.CostTable.ColumnName     = [{'Distribution Centre'}, cellstr(app.StoreNames(:)')];
            app.CostTable.ColumnEditable = [false, true(1, numel(app.StoreNames))];
            % RowName is cleared because cell-array data otherwise numbers the rows.
            app.SupplyShareTable.ColumnName     = {'Distribution Centre'; 'Supply share (%)'};
            app.SupplyShareTable.ColumnFormat   = {'char', 'bank'};
            app.SupplyShareTable.ColumnEditable = [false true];
            app.SupplyShareTable.ColumnWidth    = {'1x', 130};
            app.SupplyShareTable.RowName        = {};
            app.DemandShareTable.ColumnName     = {'Store'; 'Demand share (%)'};
            app.DemandShareTable.ColumnFormat   = {'char', 'bank'};
            app.DemandShareTable.ColumnEditable = [false true];
            app.DemandShareTable.ColumnWidth    = {'1x', 130};
            app.DemandShareTable.RowName        = {};
            % --- The shipping plan is a heatmap, which App Designer cannot place as a
            %     component, so it is created here at runtime into its grid cell.
            app.PlanHeatmap = heatmap(app.OptGrid, cellstr(app.StoreNames(:)'), ...
                cellstr(app.DcNames(:)'), zeros(numel(app.DcNames), numel(app.StoreNames)));
            app.PlanHeatmap.Layout.Row    = 6;
            app.PlanHeatmap.Layout.Column = 1;
            app.PlanHeatmap.Colormap        = sky;
            app.PlanHeatmap.CellLabelFormat = '%.0f';
            app.PlanHeatmap.FontSize        = 11;
            app.PlanHeatmap.ColorbarVisible = 'on';
            app.PlanHeatmap.Title  = 'Optimized shipping plan (units)';
            app.PlanHeatmap.XLabel = '';
            app.PlanHeatmap.YLabel = '';
            refreshAll(app);
        end

        % Value changed function: HorizonSpinner
        function onHorizonChanged(app, event)
            % Changing the horizon changes the forecast, the month lists and the plan.
            refreshAll(app);
        end

        % Value changed function: PlotStyleDropDown
        function onPlotStyleChanged(app, event)
            % Plot style is presentation only - the numbers do not move.
            drawForecast(app);
        end

        % Value changed function: FocusMonthDropDown
        function onFocusMonthChanged(app, event)
            % A promotion is the reason to ship extra stock, so the shipping month
            % follows the focus month across to the right-hand panel.
            runForecast(app);
            drawForecast(app);
            syncShipMonths(app);
            if app.FocusIdx >= 1
                app.ShipMonthDropDown.Value = app.FocusIdx;
            end
            solveTransport(app);
        end

        % Button pushed function: ResetButton
        function onReset(app, event)
            % Back to the state the app opens in: controls, costs and shares.
            app.HorizonSpinner.Value     = 6;
            app.PlotStyleDropDown.Value  = 'timeline';
            app.FocusMonthDropDown.Value = 0;
            app.ShipMonthDropDown.Value  = 1;
            app.ShipCost   = app.ShipCost0;
            app.DcShare    = app.DcShare0;
            app.StoreShare = app.StoreShare0;
            refreshAll(app);
        end

        % Value changed function: ShipMonthDropDown
        function onShipMonthChanged(app, event)
            % Re-derive demand and supply for another month, leaving the forecast alone.
            solveTransport(app);
        end

        % Button pushed function: ReloadCostsButton
        function onReloadCosts(app, event)
            % Throw away every edit and go back to the scenario in TransportOpt.xlsx.
            app.ShipCost   = app.ShipCost0;
            app.DcShare    = app.DcShare0;
            app.StoreShare = app.StoreShare0;
            solveTransport(app);
        end

        % Cell edit callback: SupplyShareTable
        function onSupplyShareEdited(app, event)
            % A distribution centre's share of total supply.
            newValue = event.NewData;
            if ~isnumeric(newValue) || ~isscalar(newValue) || ~isfinite(newValue) || newValue < 0
                app.SupplyShareTable.Data = buildShareData(app, app.DcNames, app.DcShare);
                app.StatusLamp.Color = [0.90 0.65 0.10];
                app.OptStatusLabel.Text = 'A share must be a number of 0 or more. The previous value has been restored.';
                return
            end
            app.DcShare(event.Indices(1)) = newValue / 100;
            solveTransport(app);
        end

        % Cell edit callback: DemandShareTable
        function onDemandShareEdited(app, event)
            % A store's share of total demand.
            newValue = event.NewData;
            if ~isnumeric(newValue) || ~isscalar(newValue) || ~isfinite(newValue) || newValue < 0
                app.DemandShareTable.Data = buildShareData(app, app.StoreNames, app.StoreShare);
                app.StatusLamp.Color = [0.90 0.65 0.10];
                app.OptStatusLabel.Text = 'A share must be a number of 0 or more. The previous value has been restored.';
                return
            end
            app.StoreShare(event.Indices(1)) = newValue / 100;
            solveTransport(app);
        end

        % Cell edit callback: CostTable
        function onCostEdited(app, event)
            % Keep the edited cost and re-solve, so the plan always matches the table.
            row = event.Indices(1);
            col = event.Indices(2) - 1;          % column 1 holds the distribution centre
            newValue = event.NewData;
            if ~isnumeric(newValue) || ~isscalar(newValue) || ~isfinite(newValue) || newValue < 0
                app.CostTable.Data = buildTableData(app, app.ShipCost);   % restore old value
                app.StatusLamp.Color = [0.90 0.65 0.10];
                app.OptStatusLabel.Text = ['A shipping cost must be a number of 0 or more. ' ...
                    'The previous value has been restored.'];
                return
            end
            app.ShipCost(row, col) = newValue;
            solveTransport(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BrightenUpMartAnalyticsApp';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.HeaderLabel = uilabel(app.MainGrid);
            app.HeaderLabel.Text = 'Brighten Up Mart - Forecast and Distribution Planner';
            app.BodyGrid = uigridlayout(app.MainGrid);
            app.ForecastPanel = uipanel(app.BodyGrid);
            app.ForecastPanel.Title = 'Sales Forecast';
            app.FcstGrid = uigridlayout(app.ForecastPanel);
            app.ForecastAxes = uiaxes(app.FcstGrid);
            title(app.ForecastAxes, 'Brighten Up Mart monthly unit sales')
            xlabel(app.ForecastAxes, 'Month')
            ylabel(app.ForecastAxes, 'Unit sales')
            app.FcstCtrlGrid = uigridlayout(app.FcstGrid);
            app.HorizonCaption = uilabel(app.FcstCtrlGrid);
            app.HorizonCaption.Text = 'Forecast horizon (months)';
            app.HorizonSpinner = uispinner(app.FcstCtrlGrid);
            app.HorizonSpinner.Limits = [1 12];
            app.HorizonSpinner.RoundFractionalValues = 'on';
            app.HorizonSpinner.ValueChangedFcn = createCallbackFcn(app, @onHorizonChanged, true);
            app.HorizonSpinner.Tooltip = 'How many months ahead to forecast. Capped at 12 because the model needs the same month one year earlier.';
            app.HorizonSpinner.Value = 6;
            app.PlotStyleCaption = uilabel(app.FcstCtrlGrid);
            app.PlotStyleCaption.Text = 'Plot style';
            app.PlotStyleDropDown = uidropdown(app.FcstCtrlGrid);
            app.PlotStyleDropDown.Items = {'Continuous timeline', 'Year over year'};
            app.PlotStyleDropDown.ItemsData = {'timeline', 'yoy'};
            app.PlotStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @onPlotStyleChanged, true);
            app.PlotStyleDropDown.Tooltip = 'One continuous series, or every calendar year overlaid on a 12-month axis.';
            app.PlotStyleDropDown.Value = 'timeline';
            app.FocusCaption = uilabel(app.FcstCtrlGrid);
            app.FocusCaption.Text = 'Focus marketing month';
            app.FocusMonthDropDown = uidropdown(app.FcstCtrlGrid);
            app.FocusMonthDropDown.Items = {'None'};
            app.FocusMonthDropDown.ItemsData = 0;
            app.FocusMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @onFocusMonthChanged, true);
            app.FocusMonthDropDown.Tooltip = 'Boost marketing spend in one month and mark it with a dashed line.';
            app.FocusMonthDropDown.Value = 0;
            app.ResetButton = uibutton(app.FcstCtrlGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @onReset, true);
            app.ResetButton.Tooltip = 'Restore every control, the shipping costs and the shares to their defaults.';
            app.ResetButton.Text = 'Reset';
            app.FcstStatusLabel = uilabel(app.FcstGrid);
            app.FcstStatusLabel.Text = '';
            app.OptPanel = uipanel(app.BodyGrid);
            app.OptPanel.Title = 'Transport Optimization';
            app.OptGrid = uigridlayout(app.OptPanel);
            app.OptCtrlGrid = uigridlayout(app.OptGrid);
            app.ShipMonthCaption = uilabel(app.OptCtrlGrid);
            app.ShipMonthCaption.Text = 'Shipping month';
            app.ShipMonthDropDown = uidropdown(app.OptCtrlGrid);
            app.ShipMonthDropDown.Items = {'Month 1'};
            app.ShipMonthDropDown.ItemsData = 1;
            app.ShipMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @onShipMonthChanged, true);
            app.ShipMonthDropDown.Tooltip = 'Which forecast month to ship for. Its forecast becomes total demand.';
            app.ShipMonthDropDown.Value = 1;
            app.ShipInfoLabel = uilabel(app.OptCtrlGrid);
            app.ShipInfoLabel.Text = '';
            app.ReloadCostsButton = uibutton(app.OptCtrlGrid, 'push');
            app.ReloadCostsButton.ButtonPushedFcn = createCallbackFcn(app, @onReloadCosts, true);
            app.ReloadCostsButton.Tooltip = 'Discard cost and share edits, and reload TransportOpt.xlsx.';
            app.ReloadCostsButton.Text = 'Reload Excel';
            app.SharesLabel = uilabel(app.OptGrid);
            app.SharesLabel.Text = 'Network shares (%) - editable: how total supply and total demand are split';
            app.SharesGrid = uigridlayout(app.OptGrid);
            app.SupplyShareTable = uitable(app.SharesGrid);
            app.SupplyShareTable.CellEditCallback = createCallbackFcn(app, @onSupplyShareEdited, true);
            app.SupplyShareTable.Tooltip = 'Share of total supply held by each distribution centre.';
            app.DemandShareTable = uitable(app.SharesGrid);
            app.DemandShareTable.CellEditCallback = createCallbackFcn(app, @onDemandShareEdited, true);
            app.DemandShareTable.Tooltip = 'Share of total demand taken by each store.';
            app.CostLabel = uilabel(app.OptGrid);
            app.CostLabel.Text = 'Shipping cost per unit (THB) - click any cost to edit it';
            app.CostTable = uitable(app.OptGrid);
            app.CostTable.CellEditCallback = createCallbackFcn(app, @onCostEdited, true);
            app.ResultGrid = uigridlayout(app.OptGrid);
            app.OptCostCaption = uilabel(app.ResultGrid);
            app.OptCostCaption.Text = 'Optimized cost';
            app.OptCostLabel = uilabel(app.ResultGrid);
            app.OptCostLabel.Text = '--';
            app.NaiveCostCaption = uilabel(app.ResultGrid);
            app.NaiveCostCaption.Text = 'Naive first-come cost';
            app.NaiveCostLabel = uilabel(app.ResultGrid);
            app.NaiveCostLabel.Text = '--';
            app.SavingsCaption = uilabel(app.ResultGrid);
            app.SavingsCaption.Text = 'Savings from optimizing';
            app.SavingsLabel = uilabel(app.ResultGrid);
            app.SavingsLabel.Text = '--';
            app.StatusGrid = uigridlayout(app.OptGrid);
            app.StatusLamp = uilamp(app.StatusGrid);
            app.OptStatusLabel = uilabel(app.StatusGrid);
            app.OptStatusLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrightenUpMartAnalyticsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end