classdef BrightenUpMartDashboardApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        OuterGrid          matlab.ui.container.GridLayout
        StatusLabel        matlab.ui.control.Label
        ContentGrid        matlab.ui.container.GridLayout
        TransportPanel     matlab.ui.container.Panel
        TransportGrid      matlab.ui.container.GridLayout
        KpiGrid            matlab.ui.container.GridLayout
        SavingsLabel       matlab.ui.control.Label
        NaiveCostLabel     matlab.ui.control.Label
        OptCostLabel       matlab.ui.control.Label
        StatusLamp         matlab.ui.control.Lamp
        HeatmapPanel       matlab.ui.container.Panel
        HeatmapGrid        matlab.ui.container.GridLayout
        TableauTable       matlab.ui.control.Table
        TxTopGrid          matlab.ui.container.GridLayout
        LoadDemandButton   matlab.ui.control.Button
        ShipMonthDropDown  matlab.ui.control.DropDown
        ShipMonthLabel     matlab.ui.control.Label
        TxHintLabel        matlab.ui.control.Label
        ForecastPanel      matlab.ui.container.Panel
        ForecastGrid       matlab.ui.container.GridLayout
        FocusGrid          matlab.ui.container.GridLayout
        FocusLiftLabel     matlab.ui.control.Label
        ResetFocusButton   matlab.ui.control.Button
        FocusDropDown      matlab.ui.control.DropDown
        FocusLabel         matlab.ui.control.Label
        CtrlGrid           matlab.ui.container.GridLayout
        ViewDropDown       matlab.ui.control.DropDown
        ViewLabel          matlab.ui.control.Label
        HorizonSpinner     matlab.ui.control.Spinner
        HorizonLabel       matlab.ui.control.Label
        ForecastAxes       matlab.ui.control.UIAxes
        HeaderPanel        matlab.ui.container.Panel
        HeaderGrid         matlab.ui.container.GridLayout
        ReseedButton       matlab.ui.control.Button
        TitleLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        BUS = ""
        BaseCost = ""
        BasePlannedSpend = ""
        DcNames = ""
        DcShare = ""
        ForecastTbl = ""
        MaxHorizon = ""
        PredictFcn = ""
        ShipHeatmap = ""
        SpendMode = ""
        SpendStd = ""
        StoreNames = ""
        StoreShare = ""
    end

    methods (Access = private)
        function applyShippingMonth(app, resetCosts)
            % Size the network from whichever forecast month the user is shipping.
            % resetCosts=false keeps whatever unit costs are currently in the tableau,
            % so a forecast change refreshes demand and supply without discarding the
            % user's cost edits. resetCosts=true restores the scenario costs too.
            f = app.ForecastTbl;
            idx = find(strcmp(app.ShipMonthDropDown.Value, cellstr(string(f.Month,'MMM-yyyy'))), 1);
            if isempty(idx)
                idx = 1;
            end
            totalDemand = round(f.ForecastSales(idx));
        
            demand = round(totalDemand*app.StoreShare)';
            demand(end) = demand(end) + (totalDemand - sum(demand));
        
            totalSupply = ceil(totalDemand*1.03);
            supply = round(totalSupply*app.DcShare)';
            supply(1) = supply(1) + (totalSupply - sum(supply));
        
            data = app.TableauTable.Data;
            if resetCosts || isempty(data) || ~isequal(size(data), [4 6])
                cost = app.BaseCost;
            else
                cost = data(1:3, 1:5);
            end
        
            app.TableauTable.Data = [cost, supply; demand', NaN];
            app.styleTableau();
            app.solveTransportation();
            app.StatusLabel.Text = sprintf(['Shipping month %s: %d units forecast, ' ...
                'network sized and re-optimized.'], string(f.Month(idx),'MMM-yyyy'), totalDemand);
        end
        function s = commaFormat(~, value)
            s = string(round(value));
            s = regexprep(s, '(\d)(?=(\d{3})+$)', '$1,');
        end
        function drawForecast(app)
            f = app.ForecastTbl;
            cla(app.ForecastAxes);
            % cla leaves HandleVisibility='off' children behind, so the focus marker
            % has to be removed explicitly or the lines accumulate on every redraw.
            delete(findall(app.ForecastAxes,'Type','constantline'));
            hold(app.ForecastAxes,'on');
        
            focusIdx = find(strcmp(app.FocusDropDown.Value, app.FocusDropDown.Items)) - 1;
            if strcmp(app.ViewDropDown.Value,'Year over year + forecast')
                app.drawYearOverYear(f);
                if focusIdx >= 1
                    app.markFocus(month(f.Month(focusIdx)), f.PlannedMarketingSpend(focusIdx));
                end
            else
                app.drawFullHistory(f);
                if focusIdx >= 1
                    app.markFocus(f.Month(focusIdx), f.PlannedMarketingSpend(focusIdx));
                end
            end
        
            hold(app.ForecastAxes,'off');
            grid(app.ForecastAxes,'on');
            ylabel(app.ForecastAxes,'Units Sold');
            legend(app.ForecastAxes,'Location','northwest');
        end
        function drawFullHistory(app, f)
            % Every month of history, with the forecast tacked on the end.
            ax = app.ForecastAxes;
        
            % Actual sales: blue line, circle markers.
            plot(ax, app.BUS.Month, app.BUS.Sales, '-o', 'Color','b', 'LineWidth',1.5, ...
                'MarkerSize',4, 'MarkerFaceColor','b', 'DisplayName','Actual Sales');
        
            % Thin connector so history and forecast read as one series.
            plot(ax, [app.BUS.Month(end); f.Month(1)], [app.BUS.Sales(end); f.ForecastSales(1)], ...
                '-', 'Color','r', 'LineWidth',1, 'HandleVisibility','off');
        
            % Forecast: red line, diamond markers.
            plot(ax, f.Month, f.ForecastSales, '-d', 'Color','r', 'LineWidth',1.5, ...
                'MarkerSize',6, 'MarkerFaceColor','r', 'DisplayName','ML Forecast');
        
            ax.XLimMode = 'auto';       % the year-over-year view sets these manually
            ax.XTickMode = 'auto';
            ax.XTickLabelMode = 'auto';
            xlabel(ax,'Month');
            title(ax,'Sales History and Forecast');
        end
        function drawYearOverYear(app, f)
            % One Jan-Dec axis carrying this year, last year, and the forecast -
            % the shape a manager compares month on month.
            ax = app.ForecastAxes;
            currentYear = year(app.BUS.Month(end));
            prevYear = currentYear - 1;
        
            isCurrent = year(app.BUS.Month) == currentYear;
            isPrev = year(app.BUS.Month) == prevYear;
        
            % Previous year first, so it sits behind the current-year line.
            plot(ax, month(app.BUS.Month(isPrev)), app.BUS.Sales(isPrev), '--s', ...
                'Color',[0.55 0.55 0.55], 'LineWidth',1.2, 'MarkerSize',5, ...
                'DisplayName',sprintf('%d (previous)', prevYear));
        
            plot(ax, month(app.BUS.Month(isCurrent)), app.BUS.Sales(isCurrent), '-o', ...
                'Color','b', 'LineWidth',1.5, 'MarkerSize',5, 'MarkerFaceColor','b', ...
                'DisplayName',sprintf('%d (current)', currentYear));
        
            % A forecast longer than 12 months spans more than one calendar year, so
            % give each forecast year its own line rather than letting them overwrite
            % each other on the shared Jan-Dec axis.
            fcYears = unique(year(f.Month), 'stable');
            redShade = [1 0 0; 0.70 0 0; 0.42 0 0];
            redStyle = {'-', '--', ':'};
            for iy = 1:numel(fcYears)
                sel = year(f.Month) == fcYears(iy);
                k = min(iy, size(redShade,1));
                plot(ax, month(f.Month(sel)), f.ForecastSales(sel), ...
                    'LineStyle',redStyle{k}, 'Marker','d', 'Color',redShade(k,:), ...
                    'LineWidth',1.5, 'MarkerSize',6, 'MarkerFaceColor',redShade(k,:), ...
                    'DisplayName',sprintf('%d forecast', fcYears(iy)));
            end
        
            ax.XLim = [0.5 12.5];
            ax.XTick = 1:12;
            ax.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            xlabel(ax,'Month of Year');
            title(ax,'Year over Year: Current, Previous, and Forecast');
        end
        function loadDemandForShippingMonth(app)
            % Full reload, scenario costs included - what the Reload button does.
            app.applyShippingMonth(true);
        end
        function markFocus(app, xPos, spendValue)
            % Dashed marker on the month carrying the promotional marketing push.
            xline(app.ForecastAxes, xPos, '--', sprintf('Focus: spend %d', spendValue), ...
                'Color',[0.4 0.4 0.4], 'LabelOrientation','horizontal', ...
                'LabelHorizontalAlignment','left', 'LabelVerticalAlignment','bottom', ...
                'HandleVisibility','off');
        end
        function refreshForecast(app)
            H = app.HorizonSpinner.Value;
            futureDates = app.BUS.Month(end) + calmonths(1:H)';
        
            app.syncFocusMonths(futureDates);
            focusIdx = find(strcmp(app.FocusDropDown.Value, app.FocusDropDown.Items)) - 1;   % 0 = none
        
            % Planned marketing spend: a cached Poisson draw whose mode is the historical
            % mode of MarketingSpend. The focus month is replaced by a promotional push
            % of mode + 2*std, matching the live script.
            basePlan = app.BasePlannedSpend(1:H);
            plannedSpend = basePlan;
            if focusIdx >= 1
                plannedSpend(focusIdx) = round(app.SpendMode + 2*app.SpendStd);
            end
        
            forecastSales = app.runForecast(futureDates, plannedSpend);
        
            % Quantify what the focus month's extra marketing actually bought, by
            % re-running the same forecast without the boost.
            if focusIdx >= 1
                baseSales = app.runForecast(futureDates, basePlan);
                lift = forecastSales(focusIdx) - baseSales(focusIdx);
                app.FocusLiftLabel.Text = sprintf('spend %d (%+.0f units)', plannedSpend(focusIdx), lift);
            else
                app.FocusLiftLabel.Text = '';
            end
        
            app.ForecastTbl = table(futureDates, round(forecastSales), plannedSpend, ...
                'VariableNames', {'Month','ForecastSales','PlannedMarketingSpend'});
        
            app.drawForecast();
        
            app.StatusLabel.Text = sprintf(['Forecast: %d months (%s to %s) | ' ...
                'planned spend ~ Poisson(mode = %g), focus month = %s'], H, ...
                string(futureDates(1),'MMM-yyyy'), string(futureDates(end),'MMM-yyyy'), ...
                app.SpendMode, app.FocusDropDown.Value);
        
            % The shipping-month list is driven by the forecast, so keep it in step and
            % re-derive demand every time - otherwise the shipping plan can silently
            % disagree with the forecast above it. Cost edits are preserved.
            app.syncShippingMonths();
            app.applyShippingMonth(false);
        end
        function forecastSales = runForecast(app, futureDates, plannedSpend)
            % Recursive forecast: each prediction feeds back as next month's LastMonth.
            % SameMonthLastYear reads from a growing [history; forecast] series, so
            % horizons beyond 12 months still work.
            H = numel(futureDates);
            nHist = height(app.BUS);
            series = app.BUS.Sales;
            forecastSales = zeros(H,1);
            for h = 1:H
                row = table(month(futureDates(h)), series(end), series(nHist-12+h), plannedSpend(h), ...
                    'VariableNames', {'MonthOfYear','LastMonth','SameMonthLastYear','MarketingSpend'});
                forecastSales(h) = app.PredictFcn(row);
                series(end+1) = forecastSales(h); %#ok<AGROW>
            end
        end
        function setInfeasible(app, msg)
            app.ShipHeatmap.ColorData = zeros(3,5);
            app.ShipHeatmap.Title = 'No feasible shipment plan';
            app.StatusLamp.Color = [0.85 0.2 0.2];
            app.OptCostLabel.Text = 'Optimal: -';
            app.NaiveCostLabel.Text = 'Naive: -';
            app.SavingsLabel.Text = 'Savings: -';
            app.StatusLabel.Text = msg;
        end
        function solveTransportation(app)
            data = app.TableauTable.Data;
            cost = data(1:3, 1:5);
            supply = data(1:3, 6);
            demand = data(4, 1:5)';
        
            if any(~isfinite([cost(:); supply; demand])) || any([supply; demand] < 0)
                app.setInfeasible('Supply, demand, and costs must be finite and non-negative.');
                return
            end
            if sum(supply) < sum(demand)
                app.setInfeasible(sprintf(['Infeasible: total supply (%g) is less than ' ...
                    'total demand (%g).'], sum(supply), sum(demand)));
                return
            end
        
            ship = optimvar('ship', 3, 5, 'LowerBound', 0);
            prob = optimproblem('Objective', sum(sum(cost.*ship)), 'ObjectiveSense','minimize');
            prob.Constraints.supplyLimit = sum(ship,2) <= supply;
            prob.Constraints.demandMet = sum(ship,1)' == demand;
        
            [sol, ~, exitflag] = solve(prob, 'Options', optimoptions('linprog','Display','none'));
            if exitflag <= 0 || isempty(sol)
                app.setInfeasible('Solver could not find an optimal plan for these inputs.');
                return
            end
        
            shipPlan = round(sol.ship);
            totalCost = sum(sum(cost .* sol.ship));
        
            % Naive benchmark: first-come, first-served, cost ignored.
            remaining = supply;
            alloc = zeros(3,5);
            for j = 1:5
                d = demand(j);
                for i = 1:3
                    take = min(remaining(i), d);
                    alloc(i,j) = take;
                    remaining(i) = remaining(i) - take;
                    d = d - take;
                    if d <= 0
                        break
                    end
                end
            end
            naiveCost = sum(sum(cost .* alloc));
            savings = naiveCost - totalCost;
        
            app.ShipHeatmap.ColorData = shipPlan;
            app.ShipHeatmap.Title = sprintf('Optimal Shipment Plan (units) - %g shipped', sum(shipPlan(:)));
            app.StatusLamp.Color = [0.2 0.7 0.2];
            app.OptCostLabel.Text = sprintf('Optimal: THB %s', app.commaFormat(totalCost));
            app.NaiveCostLabel.Text = sprintf('Naive: THB %s', app.commaFormat(naiveCost));
            if naiveCost > 0
                app.SavingsLabel.Text = sprintf('Savings: THB %s  (%.0f%%)', ...
                    app.commaFormat(savings), 100*savings/naiveCost);
            else
                app.SavingsLabel.Text = sprintf('Savings: THB %s', app.commaFormat(savings));
            end
            app.StatusLabel.Text = sprintf('Optimal plan found. Total shipping cost THB %s.', ...
                app.commaFormat(totalCost));
        end
        function styleTableau(app)
            removeStyle(app.TableauTable);
            addStyle(app.TableauTable, uistyle('BackgroundColor',[0.90 0.94 1.00]), 'column', 6);
            addStyle(app.TableauTable, uistyle('BackgroundColor',[1.00 0.94 0.86]), 'row', 4);
            addStyle(app.TableauTable, uistyle('BackgroundColor',[0.93 0.93 0.93]), 'cell', [4 6]);
        end
        function syncFocusMonths(app, futureDates)
            % Keep the focus-month list in step with the horizon. Default to April -
            % the Songkran promotion the live script boosts - when it is in range.
            labels = ['(none)'; cellstr(string(futureDates,'MMM-yyyy'))];
            previous = app.FocusDropDown.Value;
            app.FocusDropDown.Items = labels;
            if ismember(previous, labels)
                app.FocusDropDown.Value = previous;
            else
                k = find(month(futureDates) == 4, 1);
                if isempty(k)
                    app.FocusDropDown.Value = labels{1};
                else
                    app.FocusDropDown.Value = labels{k+1};   % +1 for the '(none)' entry
                end
            end
        end
        function changed = syncShippingMonths(app)
            f = app.ForecastTbl;
            labels = cellstr(string(f.Month,'MMM-yyyy'));
            previous = app.ShipMonthDropDown.Value;
            app.ShipMonthDropDown.Items = labels;
            if ismember(previous, labels)
                app.ShipMonthDropDown.Value = previous;
                changed = false;
            else
                % Default to April - the workshop's Songkran promotion - when the
                % horizon covers it, otherwise the busiest forecast month.
                k = find(month(f.Month) == 4, 1);
                if isempty(k)
                    [~, k] = max(f.ForecastSales);
                end
                app.ShipMonthDropDown.Value = labels{k};
                changed = true;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Brighten Up Mart - Business Analytics Dashboard';
            % Locate the workshop data in the project's data folder.
            folder = fileparts(which(class(app)));
            if isempty(folder)
                folder = pwd;
            end
            csvFile = fullfile(folder,'data','BrightenUpMartSales.csv');
            matFile = fullfile(folder,'data','Rblin.mat');
            if ~isfile(csvFile) || ~isfile(matFile)
                uialert(app.UIFigure, sprintf(['Could not find BrightenUpMartSales.csv and ' ...
                    'Rblin.mat in\n%s\nKeep this app at the root of the workshop project.'], ...
                    fullfile(folder,'data')), 'Missing data');
                return
            end
            app.BUS = readtimetable(csvFile, 'RowTimes', 'Month');
            S = load(matFile);
            fn = fieldnames(S);
            mdl = S.(fn{1});
            if ~isstruct(mdl) || ~isfield(mdl,'predictFcn')
                uialert(app.UIFigure, sprintf(['%s does not contain a Regression Learner ' ...
                    'model with a predictFcn.'], matFile), 'Bad model');
                return
            end
            app.PredictFcn = mdl.predictFcn;
            % Marketing-spend model for future months, taken from history.
            app.SpendMode = mode(app.BUS.MarketingSpend);   % Poisson mode (lambda)
            app.SpendStd = std(app.BUS.MarketingSpend);     % drives the focus-month push
            app.MaxHorizon = 24;
            rng(7)   % fix the seed so each launch reproduces the same numbers
            app.BasePlannedSpend = poissrnd(app.SpendMode, [app.MaxHorizon 1]);
            % Distribution network (from the workshop scenario), costs in units of 100 THB.
            app.DcNames = {'Bangkok DC','Chiang Mai DC','Hat Yai DC'};
            app.StoreNames = {'North','Central','East','South','Isaan'};   % short axis labels
            app.StoreShare = [0.265 0.217 0.181 0.169 0.168];
            app.DcShare = [0.47 0.29 0.24];
            app.BaseCost = 1e2*[20 6 9 25 15; 7 20 23 37 18; 37 25 26 7 28];
            % heatmap is not an App Designer component, so create it at runtime.
            app.ShipHeatmap = heatmap(app.HeatmapGrid, app.StoreNames, app.DcNames, zeros(3,5), ...
                'Title','Optimal Shipment Plan (units)', 'ColorbarVisible','on', ...
                'Colormap',flipud(hot));
            % Picks a focus month and a shipping month, seeds the tableau, then solves.
            app.refreshForecast();
        end

        % Button pushed function: ReseedButton
        function ReseedButtonPushed(app, event)
            app.BasePlannedSpend = poissrnd(app.SpendMode, [app.MaxHorizon 1]);
            app.refreshForecast();   % re-derives demand for the shipping month too
        end

        % Value changed function: HorizonSpinner
        function HorizonSpinnerValueChanged(app, event)
            app.refreshForecast();
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            app.drawForecast();   % same numbers, different view
        end

        % Value changed function: FocusDropDown
        function FocusDropDownValueChanged(app, event)
            app.refreshForecast();
        end

        % Button pushed function: ResetFocusButton
        function ResetFocusButtonPushed(app, event)
            % Clear the promotional push and show the unboosted forecast.
            if ismember('(none)', app.FocusDropDown.Items)
                app.FocusDropDown.Value = '(none)';
                app.refreshForecast();
            end
        end

        % Value changed function: ShipMonthDropDown
        function ShipMonthDropDownValueChanged(app, event)
            app.applyShippingMonth(false);   % new month, but keep any cost edits
        end

        % Button pushed function: LoadDemandButton
        function LoadDemandButtonPushed(app, event)
            app.loadDemandForShippingMonth();
        end

        % Cell edit callback: TableauTable
        function TableauTableCellEdit(app, event)
            % The bottom-right corner is not part of the problem.
            if isequal(event.Indices, [4 6])
                app.TableauTable.Data(4,6) = NaN;
                return
            end
            if ~isfinite(event.NewData)
                app.TableauTable.Data(event.Indices(1), event.Indices(2)) = event.PreviousData;
                app.StatusLabel.Text = 'Entry must be a number.';
                return
            end
            app.solveTransportation();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BrightenUpMartDashboardApp';
            app.OuterGrid = uigridlayout(app.UIFigure);
            app.HeaderPanel = uipanel(app.OuterGrid);
            app.HeaderGrid = uigridlayout(app.HeaderPanel);
            app.TitleLabel = uilabel(app.HeaderGrid);
            app.TitleLabel.Text = 'Brighten Up Mart - Business Analytics Dashboard';
            app.ReseedButton = uibutton(app.HeaderGrid, 'push');
            app.ReseedButton.ButtonPushedFcn = createCallbackFcn(app, @ReseedButtonPushed, true);
            app.ReseedButton.Text = 'New random draw';
            app.ContentGrid = uigridlayout(app.OuterGrid);
            app.ForecastPanel = uipanel(app.ContentGrid);
            app.ForecastPanel.Title = '1.  Sales Forecast';
            app.ForecastGrid = uigridlayout(app.ForecastPanel);
            app.ForecastAxes = uiaxes(app.ForecastGrid);
            title(app.ForecastAxes, 'Sales History and Forecast')
            xlabel(app.ForecastAxes, 'Month')
            ylabel(app.ForecastAxes, 'Units Sold')
            app.CtrlGrid = uigridlayout(app.ForecastGrid);
            app.HorizonLabel = uilabel(app.CtrlGrid);
            app.HorizonLabel.Text = 'Forecast horizon (months):';
            app.HorizonSpinner = uispinner(app.CtrlGrid);
            app.HorizonSpinner.Limits = [1 24];
            app.HorizonSpinner.RoundFractionalValues = 'on';
            app.HorizonSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizonSpinnerValueChanged, true);
            app.HorizonSpinner.Value = 6;
            app.ViewLabel = uilabel(app.CtrlGrid);
            app.ViewLabel.Text = 'View:';
            app.ViewDropDown = uidropdown(app.CtrlGrid);
            app.ViewDropDown.Items = {'Full history + forecast', 'Year over year + forecast'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Full history + forecast';
            app.FocusGrid = uigridlayout(app.ForecastGrid);
            app.FocusLabel = uilabel(app.FocusGrid);
            app.FocusLabel.Text = 'Focus marketing month:';
            app.FocusDropDown = uidropdown(app.FocusGrid);
            app.FocusDropDown.Items = {'-'};
            app.FocusDropDown.ValueChangedFcn = createCallbackFcn(app, @FocusDropDownValueChanged, true);
            app.FocusDropDown.Value = '-';
            app.ResetFocusButton = uibutton(app.FocusGrid, 'push');
            app.ResetFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFocusButtonPushed, true);
            app.ResetFocusButton.Tooltip = 'Clear the focus-month marketing boost';
            app.ResetFocusButton.Text = 'Reset';
            app.FocusLiftLabel = uilabel(app.FocusGrid);
            app.FocusLiftLabel.Text = '';
            app.TransportPanel = uipanel(app.ContentGrid);
            app.TransportPanel.Title = '2.  Transportation Optimization';
            app.TransportGrid = uigridlayout(app.TransportPanel);
            app.TxTopGrid = uigridlayout(app.TransportGrid);
            app.TxHintLabel = uilabel(app.TxTopGrid);
            app.TxHintLabel.Text = 'Edit any cell to re-optimize.';
            app.ShipMonthLabel = uilabel(app.TxTopGrid);
            app.ShipMonthLabel.Text = 'Shipping month:';
            app.ShipMonthDropDown = uidropdown(app.TxTopGrid);
            app.ShipMonthDropDown.Items = {'-'};
            app.ShipMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @ShipMonthDropDownValueChanged, true);
            app.ShipMonthDropDown.Value = '-';
            app.LoadDemandButton = uibutton(app.TxTopGrid, 'push');
            app.LoadDemandButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemandButtonPushed, true);
            app.LoadDemandButton.Text = 'Reload from forecast';
            app.TableauTable = uitable(app.TransportGrid);
            app.TableauTable.ColumnName = {'North'; 'Central'; 'East'; 'South'; 'Isaan'; 'Supply'};
            app.TableauTable.RowName = {'Bangkok DC'; 'Chiang Mai DC'; 'Hat Yai DC'; 'Demand'};
            app.TableauTable.ColumnEditable = [true true true true true true];
            app.TableauTable.CellEditCallback = createCallbackFcn(app, @TableauTableCellEdit, true);
            app.TableauTable.Tooltip = 'Cost cells are white; the Supply column and Demand row are shaded.';
            app.HeatmapPanel = uipanel(app.TransportGrid);
            app.HeatmapGrid = uigridlayout(app.HeatmapPanel);
            app.KpiGrid = uigridlayout(app.TransportGrid);
            app.StatusLamp = uilamp(app.KpiGrid);
            app.OptCostLabel = uilabel(app.KpiGrid);
            app.OptCostLabel.Text = 'Optimal: -';
            app.NaiveCostLabel = uilabel(app.KpiGrid);
            app.NaiveCostLabel.Text = 'Naive: -';
            app.SavingsLabel = uilabel(app.KpiGrid);
            app.SavingsLabel.Text = 'Savings: -';
            app.StatusLabel = uilabel(app.OuterGrid);
            app.StatusLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrightenUpMartDashboardApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end